classdef RainbowSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        hSlider                matlab.ui.control.Slider
        hSliderLabel           matlab.ui.control.Label
        OutputDataPanel        matlab.ui.container.Panel
        pt3Field               matlab.ui.control.EditField
        pt2Field               matlab.ui.control.EditField
        pt1Field               matlab.ui.control.EditField
        alphaField             matlab.ui.control.NumericEditField
        hField                 matlab.ui.control.NumericEditField
        x_3y_3Label            matlab.ui.control.Label
        x_2y_2Label            matlab.ui.control.Label
        x_1y_1Label            matlab.ui.control.Label
        alphadegreesLabel      matlab.ui.control.Label
        hLabel                 matlab.ui.control.Label
        ReferenceDiagramLabel  matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        alphaAngle;
        x1;
        y1;
        x2;
        y2;
        x3;
        y3;
    end
    
    methods (Access = private)
        
        function updateOutput(app)
            % Updates the output in the edit fields
            app.hField.Value = app.hSlider.Value;
            app.alphaField.Value = app.alphaAngle;
            app.pt1Field.Value = sprintf("(%.4f, %.4f)", app.x1, app.y1);
            app.pt2Field.Value = sprintf("(%.4f, %.4f)", app.x2, app.y2);
            app.pt3Field.Value = sprintf("(%.4f, %.4f)", app.x3, app.y3);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("Calculations/");
            app.hSlider.Value = 0.5; % Default Value
            h = app.hSlider.Value;
            ax = app.UIAxes;
            [app.alphaAngle, app.x1, app.y1, app.x2, app.y2, app.x3, app.y3] = plotRainbowDiagram(ax, h);
            updateOutput(app);
        end

        % Value changing function: hSlider
        function hSliderValueChanging(app, event)
            changingValue = event.Value;
            h = changingValue;
            ax = app.UIAxes;
            [app.alphaAngle, app.x1, app.y1, app.x2, app.y2, app.x3, app.y3] = plotRainbowDiagram(ax, h);
            updateOutput(app);            
        end

        % Value changed function: hSlider
        function hSliderValueChanged(app, event)
            value = app.hSlider.Value;
            h = value;
            ax = app.UIAxes;
            [app.alphaAngle, app.x1, app.y1, app.x2, app.y2, app.x3, app.y3] = plotRainbowDiagram(ax, h);
            updateOutput(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'RainbowDiagram.png');
            app.ReferenceDiagramLabel = uilabel(app.UIFigure);
            app.ReferenceDiagramLabel.Text = 'Reference Diagram';
            app.OutputDataPanel = uipanel(app.UIFigure);
            app.OutputDataPanel.Title = 'Output Data';
            app.hLabel = uilabel(app.OutputDataPanel);
            app.hLabel.Text = 'h';
            app.alphadegreesLabel = uilabel(app.OutputDataPanel);
            app.alphadegreesLabel.Interpreter = 'tex';
            app.alphadegreesLabel.Text = '\alpha (degrees)';
            app.x_1y_1Label = uilabel(app.OutputDataPanel);
            app.x_1y_1Label.Interpreter = 'tex';
            app.x_1y_1Label.Text = '(x_1, y_1)';
            app.x_2y_2Label = uilabel(app.OutputDataPanel);
            app.x_2y_2Label.Interpreter = 'tex';
            app.x_2y_2Label.Text = '(x_2, y_2)';
            app.x_3y_3Label = uilabel(app.OutputDataPanel);
            app.x_3y_3Label.Interpreter = 'tex';
            app.x_3y_3Label.Text = '(x_3, y_3)';
            app.hField = uieditfield(app.OutputDataPanel, 'numeric');
            app.hField.Editable = 'off';
            app.alphaField = uieditfield(app.OutputDataPanel, 'numeric');
            app.alphaField.Editable = 'off';
            app.pt1Field = uieditfield(app.OutputDataPanel, 'text');
            app.pt1Field.Editable = 'off';
            app.pt2Field = uieditfield(app.OutputDataPanel, 'text');
            app.pt2Field.Editable = 'off';
            app.pt3Field = uieditfield(app.OutputDataPanel, 'text');
            app.pt3Field.Editable = 'off';
            app.hSliderLabel = uilabel(app.UIFigure);
            app.hSliderLabel.Text = 'h';
            app.hSlider = uislider(app.UIFigure);
            app.hSlider.Limits = [0.01 0.99];
            app.hSlider.MajorTicks = [0.01 0.25 0.5 0.75 0.99];
            app.hSlider.ValueChangedFcn = createCallbackFcn(app, @hSliderValueChanged, true);
            app.hSlider.ValueChangingFcn = createCallbackFcn(app, @hSliderValueChanging, true);
            app.hSlider.Value = 0.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RainbowSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end